classdef ece480_final_project_VER_1_2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolationMethodButtonGroup_2  matlab.ui.container.ButtonGroup
        SplineButton                    matlab.ui.control.RadioButton
        CubicButton                     matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        NearestButton_2                 matlab.ui.control.RadioButton
        UIFigure                        matlab.ui.Figure
        ECE480ImageProcessingMidtermProjectPanel  matlab.ui.container.Panel
        UndoButton_2                    matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IntensityandSpatialFiltering    matlab.ui.container.Tab
        HistogramButton                 matlab.ui.control.Button
        LogTransformButton              matlab.ui.control.Button
        AdaptiveHistogramEqualizationPanel  matlab.ui.container.Panel
        AlphaEditField_2                matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        HistogramEqualizationButton     matlab.ui.control.Button
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        ClipLimitEditField              matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel         matlab.ui.control.Label
        DistributionButtonGroup         matlab.ui.container.ButtonGroup
        ExponentialButton_2             matlab.ui.control.RadioButton
        RayleighButton_2                matlab.ui.control.RadioButton
        UniformButton                   matlab.ui.control.RadioButton
        NumberofTilesLable              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        MEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        KernalFilteringPanel            matlab.ui.container.Panel
        lenEditField                    matlab.ui.control.EditField
        lenEditFieldLabel               matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.EditField
        alphaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.EditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        radiusEditField                 matlab.ui.control.EditField
        radiusEditFieldLabel            matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.EditField
        thetaEditFieldLabel             matlab.ui.control.Label
        SobelButton                     matlab.ui.control.Button
        PrewittButton                   matlab.ui.control.Button
        MotionButton                    matlab.ui.control.Button
        LoGButton                       matlab.ui.control.Button
        DiskButton                      matlab.ui.control.Button
        AverageButton                   matlab.ui.control.Button
        hEditField                      matlab.ui.control.EditField
        hEditFieldLabel                 matlab.ui.control.Label
        LaplacianButton                 matlab.ui.control.Button
        GaussianButton                  matlab.ui.control.Button
        ContrastStretchingPanel         matlab.ui.container.Panel
        KmeanvalueCheckBox              matlab.ui.control.CheckBox
        KEditField                      matlab.ui.control.EditField
        KEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.EditField
        EEditFieldLabel                 matlab.ui.control.Label
        FilterButton                    matlab.ui.control.Button
        SharpenPanel                    matlab.ui.container.Panel
        AmountEditField                 matlab.ui.control.EditField
        AmountEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.EditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        SharpenButton                   matlab.ui.control.Button
        FrequencyDomainFilteringTab     matlab.ui.container.Tab
        NotchFiltering                  matlab.ui.container.Panel
        NotchFIlterOrderEditField       matlab.ui.control.NumericEditField
        NotchFIlterOrderEditFieldLabel  matlab.ui.control.Label
        NotchRadiusEditField            matlab.ui.control.NumericEditField
        NotchRadiusEditFieldLabel       matlab.ui.control.Label
        PlaceNotchesButton              matlab.ui.control.Button
        ApplyFilterButton               matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        Notchfiltertype                 matlab.ui.container.ButtonGroup
        GaussianButton_3                matlab.ui.control.RadioButton
        ButterworthButton_2             matlab.ui.control.RadioButton
        IdealButton_2                   matlab.ui.control.RadioButton
        NotchType                       matlab.ui.container.ButtonGroup
        PassButton                      matlab.ui.control.RadioButton
        RejectButton                    matlab.ui.control.RadioButton
        ShowFFTPlotsSwitch              matlab.ui.control.Switch
        ShowFFTPlotsSwitchLabel         matlab.ui.control.Label
        PaddingMethodDropDown           matlab.ui.control.DropDown
        PaddingMethodDropDownLabel      matlab.ui.control.Label
        WidthoftheBandEditField         matlab.ui.control.NumericEditField
        WidthoftheBandEditFieldLabel    matlab.ui.control.Label
        CenterFrequencyEditField        matlab.ui.control.NumericEditField
        CenterFrequencyEditFieldLabel   matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        CutoffFrequencyEditFieldLabel   matlab.ui.control.Label
        FilterTypeButtonGroup           matlab.ui.container.ButtonGroup
        BandrejectButton                matlab.ui.control.RadioButton
        BandpassButton                  matlab.ui.control.RadioButton
        HighpassButton                  matlab.ui.control.RadioButton
        LowpassButton                   matlab.ui.control.RadioButton
        GaussianButton_2                matlab.ui.control.Button
        IdealButton                     matlab.ui.control.Button
        ButterworthButton               matlab.ui.control.Button
        ImageRestorationTab             matlab.ui.container.Tab
        FilterPanel                     matlab.ui.container.Panel
        sqrtvarCheckBox                 matlab.ui.control.CheckBox
        NSPRCheckBox                    matlab.ui.control.CheckBox
        autocorrelationCheckBox         matlab.ui.control.CheckBox
        BlindDeconvolutionButton        matlab.ui.control.Button
        dampeningEditField              matlab.ui.control.NumericEditField
        dampeningEditFieldLabel         matlab.ui.control.Label
        iterationEditField              matlab.ui.control.NumericEditField
        iterationEditFieldLabel         matlab.ui.control.Label
        LucyRichardsonButton            matlab.ui.control.Button
        noisepowerEditField_2           matlab.ui.control.NumericEditField
        noisepowerEditField_2Label      matlab.ui.control.Label
        ConstrainedLeastSquaresButton   matlab.ui.control.Button
        noisepowerEditField             matlab.ui.control.NumericEditField
        noisepowerEditFieldLabel        matlab.ui.control.Label
        WienerFilterButton              matlab.ui.control.Button
        varEditField                    matlab.ui.control.NumericEditField
        varEditFieldLabel               matlab.ui.control.Label
        meanEditField                   matlab.ui.control.NumericEditField
        meanEditFieldLabel              matlab.ui.control.Label
        PointsSquareFunctionPanel       matlab.ui.container.Panel
        SobelCheckBox                   matlab.ui.control.CheckBox
        LoGCheckBox                     matlab.ui.control.CheckBox
        PrewittCheckBox                 matlab.ui.control.CheckBox
        MotionCheckBox                  matlab.ui.control.CheckBox
        DiskCheckBox                    matlab.ui.control.CheckBox
        AverageCheckBox                 matlab.ui.control.CheckBox
        LaplacianCheckBox               matlab.ui.control.CheckBox
        GaussianCheckBox                matlab.ui.control.CheckBox
        lenEditField_2                  matlab.ui.control.EditField
        lenEditField_2Label             matlab.ui.control.Label
        alphaEditField_2                matlab.ui.control.EditField
        alphaEditField_2Label           matlab.ui.control.Label
        sigmaEditField_2                matlab.ui.control.EditField
        sigmaEditField_2Label           matlab.ui.control.Label
        radiusEditField_2               matlab.ui.control.EditField
        radiusEditField_2Label          matlab.ui.control.Label
        thetaEditField_2                matlab.ui.control.EditField
        thetaEditField_2Label           matlab.ui.control.Label
        hEditField_2                    matlab.ui.control.EditField
        hEditField_2Label               matlab.ui.control.Label
        NoiseFilteringPanel             matlab.ui.container.Panel
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        alphatrimmedCheckBox            matlab.ui.control.CheckBox
        midpointCheckBox                matlab.ui.control.CheckBox
        maxCheckBox                     matlab.ui.control.CheckBox
        minCheckBox                     matlab.ui.control.CheckBox
        medianCheckBox                  matlab.ui.control.CheckBox
        contraharmonicCheckBox          matlab.ui.control.CheckBox
        harmonicCheckBox                matlab.ui.control.CheckBox
        geometricCheckBox               matlab.ui.control.CheckBox
        arithmeticCheckBox              matlab.ui.control.CheckBox
        FilterButton_2                  matlab.ui.control.Button
        SinusoidalButton                matlab.ui.control.Button
        ExponentialButton               matlab.ui.control.Button
        ErlangButton                    matlab.ui.control.Button
        RayleighButton                  matlab.ui.control.Button
        SaltandPepperButton             matlab.ui.control.Button
        GaussianButton_4                matlab.ui.control.Button
        ImageReconstructionTab          matlab.ui.container.Tab
        FanBeamProjectionsPanel         matlab.ui.container.Panel
        InterpolationButtonGroup        matlab.ui.container.ButtonGroup
        SplineButton_2                  matlab.ui.control.RadioButton
        CubicButton_2                   matlab.ui.control.RadioButton
        LinearButton_2                  matlab.ui.control.RadioButton
        NearestButton_3                 matlab.ui.control.RadioButton
        FilterTypeButtonGroup_3         matlab.ui.container.ButtonGroup
        NoneButton_3                    matlab.ui.control.RadioButton
        HannButton_2                    matlab.ui.control.RadioButton
        HammingButton_2                 matlab.ui.control.RadioButton
        CosineButton_2                  matlab.ui.control.RadioButton
        SheppLoganButton_2              matlab.ui.control.RadioButton
        RamLakButton_2                  matlab.ui.control.RadioButton
        SensorSpacingSlider             matlab.ui.control.Slider
        SensorSpacingSliderLabel        matlab.ui.control.Label
        ReconstructionButton_2          matlab.ui.control.Button
        SensorGeometryButtonGroup       matlab.ui.container.ButtonGroup
        LineButton                      matlab.ui.control.RadioButton
        ArcButton                       matlab.ui.control.RadioButton
        FanBeamTransformButton          matlab.ui.control.Button
        RotationIncrementSlider         matlab.ui.control.Slider
        RotationIncrementSliderLabel    matlab.ui.control.Label
        ParallelBeamProjectionsPanel    matlab.ui.container.Panel
        FilterTypeButtonGroup_2         matlab.ui.container.ButtonGroup
        NoneButton_2                    matlab.ui.control.RadioButton
        HannButton                      matlab.ui.control.RadioButton
        HammingButton                   matlab.ui.control.RadioButton
        CosineButton                    matlab.ui.control.RadioButton
        SheppLoganButton                matlab.ui.control.RadioButton
        RamLakButton                    matlab.ui.control.RadioButton
        ReconstructionButton            matlab.ui.control.Button
        RadonTransformButton            matlab.ui.control.Button
        ThetaSpacingSlider              matlab.ui.control.Slider
        ThetaSpacingSliderLabel         matlab.ui.control.Label
        Transformations                 matlab.ui.container.Tab
        InterpolationMethodButtonGroup  matlab.ui.container.ButtonGroup
        BicubicButton                   matlab.ui.control.RadioButton
        BilinearButton                  matlab.ui.control.RadioButton
        NearestButton                   matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        HorizontalShearButton           matlab.ui.control.Button
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        VerticalShearButton             matlab.ui.control.Button
        FlipHorizontalButton            matlab.ui.control.Button
        FlipVerticalButton              matlab.ui.control.Button
        RotateImageButton               matlab.ui.control.Button
        DegreesEditField                matlab.ui.control.NumericEditField
        DegreesEditFieldLabel           matlab.ui.control.Label
        YTranslateEditField             matlab.ui.control.NumericEditField
        YTranslateEditFieldLabel        matlab.ui.control.Label
        XTranslateEditField             matlab.ui.control.NumericEditField
        XTranslateEditFieldLabel        matlab.ui.control.Label
        TranslateImageButton            matlab.ui.control.Button
        ScaleImageButton                matlab.ui.control.Button
        YScalingEditField               matlab.ui.control.NumericEditField
        YScalingEditFieldLabel          matlab.ui.control.Label
        XScalingEditField               matlab.ui.control.NumericEditField
        XScalingEditFieldLabel          matlab.ui.control.Label
        OrignalPanel                    matlab.ui.container.Panel
        orignal                         matlab.ui.control.Image
        FilteredImagePanel              matlab.ui.container.Panel
        filtered                        matlab.ui.control.Image
        FileInformationPanel            matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        FileDetailsButton               matlab.ui.control.Button
        SelectImageButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_file_name % Description
        image_info
        kmean
        k_value
        e_value
        image_color
        save_as
        log_image
        hist_image
        gs
        current_image
        done
        im
        gauss_image
        lap_image
        avg_image
        disk_image
        lapofgauss_image
        sobel_image
        prewitt_image
        motion_image
        alpha
        theta
        h
        sigma
        radius
        len
        sharpen_rad
        amount
        sharp_image
        image_array
        D0
        n
        C0
        W
        Ideal_image
        Butterworth_image
        Gaussian_image
        padMethod
        FFTplot_bool
        Notch_image
        Notch_transfer
        notch_filter_order
        notch_radius
        centers
        radii
        fft
        at_least_one
        fig
        x_scale
        y_scale
        x_trans
        y_trans
        degrees
        alpha_shear
        a
        b
        c
        gauss_noise_image
        filt_im
        SandP
        rayleigh
        erlang
        exponential
        sinusoid
        blindDeconv
        constLS
        lucy
        weiner
        M
        N
        clip_limit
        num_bins
        alpha_eq
        theta_space
        sensor_space
        rotation_inc
        D
        projection_im
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
           filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'}
           [filename,filepath] = uigetfile(filterspec);
           fullname = [filepath, filename];
           app.image_file_name=filename;
           app.image_info=imread(filename);
         %  app.image_info=rgb2gray(app.image_color);
           app.orignal.ImageSource = filename;
           app.done=0
           app.image_array = [];
           app.image_array = cat(3,app.image_array,app.image_info);
        end

        % Button pushed function: FileDetailsButton
        function FileDetailsButtonPushed(app, event)
            imageinfo(app.image_file_name)
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.hist_image=histeq(app.im)
            app.filtered.ImageSource=app.hist_image
            app.current_image=app.hist_image
            app.image_array = cat(3, app.image_array, app.hist_image);
            app.done=1
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            E=app.e_value
            if app.done==0;
                app.im=app.image_info
            else
                app.im=app.current_image
            end
            if app.kmean==1
                k=mean2(double(app.im))
            else 
                k=app.k_value
            end
            gam=1./(1+(k./double(app.im)).^E);
            app.gs=im2uint8(mat2gray(gam));
            app.filtered.ImageSource=app.gs;
            app.current_image=app.gs;
            app.image_array = cat(3, app.image_array, app.gs);
            app.done=1;
        end

        % Value changed function: KmeanvalueCheckBox
        function KmeanvalueCheckBoxValueChanged(app, event)
            value = app.KmeanvalueCheckBox.Value;
            app.kmean=value;
        end

        % Value changing function: EEditField
        function EEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: KEditField
        function KEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            value = app.EEditField.Value;
            app.e_value=str2num(value);
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            value = app.KEditField.Value;
            app.k_value=str2num(value);
        end

        % Button pushed function: LogTransformButton
        function LogTransformButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info
            else
                app.im=app.current_image
            end
            g=log(1+double(app.im));
            app.log_image=im2uint8(mat2gray(g));
            app.filtered.ImageSource=app.log_image;
            app.current_image=app.log_image;
            app.image_array = cat(3, app.image_array, app.log_image);
            app.done=1;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg','*.png'});
            newfilename=fullfile(pathname, filename)
            figure
            imshow(app.filtered.ImageSource)
            saveas(gcf, newfilename)
            close all
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.filtered.ImageSource=app.image_info;
            app.current_image=app.image_info;
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.gauss_image=imfilter(app.im, fspecial('gaussian', app.h, app.sigma))
            app.filtered.ImageSource=app.gauss_image
            app.current_image=app.gauss_image
            app.image_array = cat(3, app.image_array, app.gauss_image);
            app.done=1
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.lap_image=imfilter(app.im, fspecial('laplacian', app.alpha))
            app.filtered.ImageSource=app.lap_image
            app.current_image=app.lap_image
            app.image_array = cat(3, app.image_array, app.lap_image);
            app.done=1
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.avg_image=imfilter(app.im, fspecial('average', app.h))
            app.filtered.ImageSource=app.avg_image
            app.current_image=app.avg_image
            app.image_array = cat(3, app.image_array, app.avg_image);
            app.done=1
        end

        % Button pushed function: DiskButton
        function DiskButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
             end
             app.radius
            app.disk_image=imfilter(app.im, fspecial('disk', app.radius))
            app.filtered.ImageSource=app.disk_image
            app.current_image=app.disk_image
            app.image_array = cat(3, app.image_array, app.disk_image);
            app.done=1
        end

        % Button pushed function: LoGButton
        function LoGButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.lapofgauss_image=imfilter(app.im, fspecial('log', app.h, app.sigma))
            app.filtered.ImageSource=app.lapofgauss_image
            app.current_image=app.lapofgauss_image
            app.image_array = cat(3, app.image_array, app.lapofgauss_image);
            app.done=1
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.sobel_image=imfilter(app.im, fspecial('sobel'))
            app.filtered.ImageSource=app.sobel_image
            app.current_image=app.sobel_image
            app.image_array = cat(3, app.image_array, app.sobel_image);
            app.done=1
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.prewitt_image=imfilter(app.im, fspecial('prewitt'))
            app.filtered.ImageSource=app.prewitt_image
            app.current_image=app.prewitt_image
            app.image_array = cat(3, app.image_array, app.prewitt_image);
            app.done=1
        end

        % Button pushed function: MotionButton
        function MotionButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.motion_image=imfilter(app.im, fspecial('motion', app.len, app.theta))
            app.filtered.ImageSource=app.motion_image
            app.current_image=app.motion_image
            app.image_array = cat(3, app.image_array, app.motion_image);
            app.done=1
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            value = app.alphaEditField.Value;
            app.alpha=str2double(value)
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            app.sigma=str2double(value)
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            app.h=str2double(value)
        end

        % Value changed function: thetaEditField
        function thetaEditFieldValueChanged(app, event)
            value = app.thetaEditField.Value;
            app.theta=str2double(value)
        end

        % Value changed function: radiusEditField
        function radiusEditFieldValueChanged(app, event)
            value = app.radiusEditField.Value;
            app.radius=str2double(value)
        end

        % Value changed function: lenEditField
        function lenEditFieldValueChanged(app, event)
            value = app.lenEditField.Value;
            app.len=str2double(value)
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.sharp_image=imsharpen(app.im, Radius=app.sharpen_rad, Amount=app.amount)
            app.filtered.ImageSource=app.sharp_image
            app.current_image=app.sharp_image
            app.image_array = cat(3, app.image_array, app.sharp_image);
            app.done=1
        end

        % Value changed function: AmountEditField
        function AmountEditFieldValueChanged(app, event)
            value = app.AmountEditField.Value;
            app.amount=str2double(value)
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            value = app.RadiusEditField.Value;
            app.sharpen_rad=str2double(value)
        end

        % Value changed function: PaddingMethodDropDown
        function PaddingMethodDropDownValueChanged(app, event)
            value = app.PaddingMethodDropDown.Value;
            app.padMethod = value;
        end

        % Value changed function: NotchFIlterOrderEditField
        function NotchFIlterOrderEditFieldValueChanged(app, event)
            value = app.NotchFIlterOrderEditField.Value;
            app.notch_filter_order = value;
        end

        % Value changed function: NotchRadiusEditField
        function NotchRadiusEditFieldValueChanged(app, event)
            value = app.NotchRadiusEditField.Value;
            app.notch_radius = value;
        end

        % Button pushed function: PlaceNotchesButton
        function PlaceNotchesButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            if app.GaussianButton_3.Value == 1
                filter_type = 'gaussian';
            elseif app.ButterworthButton_2.Value == 1
                filter_type = 'butterworth';
            else
                filter_type = 'ideal';
            end
            
            if app.PassButton.Value == 1
                notch_type = 'pass';
            else
                notch_type = 'reject';
            end

            app.Notchfiltertype.Enable = "off";
            app.NotchType.Enable = "off";
            
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');
            fp = padarray(app.im, [PQ(1)-M(1), PQ(2)-M(2)], 'post');
            
            F = fft2(fp);
            Fc = fftshift(F);
            
            app.fft = mat2gray(1 + log(abs(Fc)));
            if app.at_least_one == false
                figure;
            end

            while true
                if isempty(app.centers) || isempty(app.radii)
                    imshow(app.fft); title('Image FFT with Notch Filtering')
                else
                    H = notchfilter(filter_type, notch_type, PQ(1), PQ(2), app.centers, app.radii, app.notch_filter_order);
                    Hc = fftshift(H);
                    S1 = (Hc.*app.fft);
                    imshow(S1); title('Image FFT with Notch Filtering')
                    app.Notch_transfer = H;
                end
                
                C_in = ginput(1);
                if not(isempty(C_in))
                    C_in(1) = PQ(1)/2 - C_in(1);
                    C_in(2) = PQ(2)/2 - C_in(2);
                    
                    v = C_in(1);
                    C_in(1) = C_in(2);
                    C_in(2) = v;
                
                    app.centers = cat(1, app.centers, round(C_in));
                    app.radii = cat(1, app.radii, app.notch_radius);
                    app.at_least_one = true;
                else
                    break
                end
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.Notch_image = dftfilt(app.im, app.Notch_transfer, app.padMethod);
            app.filtered.ImageSource = app.Notch_image;
            close all
            if app.FFTplot_bool == 1
                showfftplots(app.im, app.Notch_transfer, app.padMethod);
            end
            app.current_image = app.Notch_image;
            app.image_array = cat(3, app.image_array, app.Notch_image);
            app.done = 1;
            
            app.centers = [];
            app.radii = [];
            app.Notch_transfer = [];
            app.Notchfiltertype.Enable = "on";
            app.NotchType.Enable = "on";
            app.at_least_one = false;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.centers = [];
            app.radii = [];
            app.Notch_transfer = [];
            imshow(app.fft); title('Image FFT with Notch Filtering')
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.centers(end,:) = [];
            app.radii(end,:) = [];
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.GaussianButton_3.Value == 1
                filter_type = 'gaussian';
            elseif app.ButterworthButton_2.Value == 1
                filter_type = 'butterworth';
            else
                filter_type = 'ideal';
            end
            
            if app.PassButton.Value == 1
                notch_type = 'pass';
            else
                notch_type = 'reject';
            end
            
            if isempty(app.centers) || isempty(app.radii)
                imshow(app.fft); title('Image FFT with Notch Filtering')
            else
                H = notchfilter(filter_type, notch_type, PQ(1), PQ(2), app.centers, app.radii, app.notch_filter_order);
                Hc = fftshift(H);
                S1 = (Hc.*app.fft);
                imshow(S1); title('Image FFT with Notch Filtering')
                app.Notch_transfer = H;
            end
        end

        % Value changed function: ShowFFTPlotsSwitch
        function ShowFFTPlotsSwitchValueChanged(app, event)
            value = app.ShowFFTPlotsSwitch.Value;
            app.FFTplot_bool = value;
        end

        % Value changed function: WidthoftheBandEditField
        function WidthoftheBandEditFieldValueChanged(app, event)
            value = app.WidthoftheBandEditField.Value;
            app.W = value;
        end

        % Value changed function: CenterFrequencyEditField
        function CenterFrequencyEditFieldValueChanged(app, event)
            value = app.CenterFrequencyEditField.Value;
            app.C0 = value;
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            app.n = value;
        end

        % Value changed function: CutoffFrequencyEditField
        function CutoffFrequencyEditFieldValueChanged(app, event)
            value = app.CutoffFrequencyEditField.Value;
            app.D0 = value;
        end

        % Button pushed function: GaussianButton_2
        function GaussianButton_2Pushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.LowpassButton.Value == 1
                H = lpfilter('gaussian', PQ(1),PQ(2),app.D0);
            elseif app.HighpassButton.Value == 1
                H = hpfilter('gaussian', PQ(1),PQ(2),app.D0);
            elseif app.BandpassButton.Value == 1
                H = bandfilter('gaussian', 'pass', PQ(1), PQ(2), app.C0, app.W);
            elseif app.BandrejectButton.Value == 1
                H = bandfilter('gaussian', 'reject', PQ(1), PQ(2), app.C0, app.W);
            end

            app.Gaussian_image = dftfilt(app.im, H, app.padMethod);
            if app.FFTplot_bool == 1
                showfftplots(app.im, H, app.padMethod);
            end
            app.filtered.ImageSource = app.Gaussian_image;
            app.current_image = app.Gaussian_image;
            app.image_array = cat(3, app.image_array, app.Gaussian_image);
            app.done = 1;
        end

        % Button pushed function: IdealButton
        function IdealButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.LowpassButton.Value == 1
                H = lpfilter('ideal', PQ(1),PQ(2),app.D0);
            elseif app.HighpassButton.Value == 1
                H = hpfilter('ideal', PQ(1),PQ(2),app.D0);
            elseif app.BandpassButton.Value == 1
                H = bandfilter('ideal', 'pass', PQ(1), PQ(2), app.C0, app.W);
            elseif app.BandrejectButton.Value == 1
                H = bandfilter('ideal', 'reject', PQ(1), PQ(2), app.C0, app.W);
            end

            app.Ideal_image = dftfilt(app.im, H, app.padMethod);
            if app.FFTplot_bool == 1
                showfftplots(app.im, H, app.padMethod);
            end
            app.filtered.ImageSource = app.Ideal_image;
            app.current_image = app.Ideal_image;
            app.image_array = cat(3, app.image_array, app.Ideal_image);
            app.done = 1;
        end

        % Button pushed function: ButterworthButton
        function ButterworthButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.LowpassButton.Value == 1
                H = lpfilter('butterworth', PQ(1),PQ(2),app.D0);
            elseif app.HighpassButton.Value == 1
                H = hpfilter('butterworth', PQ(1),PQ(2),app.D0);
            elseif app.BandpassButton.Value == 1
                H = bandfilter('butterworth', 'pass', PQ(1), PQ(2), app.C0, app.W, app.n);
            elseif app.BandrejectButton.Value == 1
                H = bandfilter('butterworth', 'reject', PQ(1), PQ(2), app.C0, app.W, app.n);
            end

            app.Butterworth_image = dftfilt(app.im, H, app.padMethod);
            if app.FFTplot_bool == 1
                showfftplots(app.im, H, app.padMethod);
            end
            app.filtered.ImageSource = app.Butterworth_image;
            app.current_image = app.Butterworth_image;
            app.image_array = cat(3, app.image_array, app.Butterworth_image);
            app.done = 1;
        end

        % Button pushed function: UndoButton_2
        function UndoButton_2Pushed(app, event)
            if not(isempty(app.image_array))
                app.image_array(:,:,end-2:end) = [];
                if isempty(app.image_array)
                    app.filtered.ImageSource = app.image_info;
                    app.current_image = app.image_info;
                else
                    app.filtered.ImageSource = app.image_array(:,:,end-2:end);
                    app.current_image = app.image_array(:,:,end-2:end);
                end
            end
            app.done = 1;
        end

        % Value changed function: XScalingEditField
        function XScalingEditFieldValueChanged(app, event)
            value = app.XScalingEditField.Value;
            app.x_scale = value;
        end

        % Value changed function: YScalingEditField
        function YScalingEditFieldValueChanged(app, event)
            value = app.YScalingEditField.Value;
            app.y_scale = value;
        end

        % Value changed function: XTranslateEditField
        function XTranslateEditFieldValueChanged(app, event)
            value = app.XTranslateEditField.Value;
            app.x_trans = value;
        end

        % Value changed function: YTranslateEditField
        function YTranslateEditFieldValueChanged(app, event)
            value = app.YTranslateEditField.Value;
            app.y_trans = value;
        end

        % Callback function
        function InterpolationMethodDropDownValueChanged(app, event)
            value = app.InterpolationMethodDropDown.Value;
            app.interp_method = value;
        end

        % Value changed function: DegreesEditField
        function DegreesEditFieldValueChanged(app, event)
            value = app.DegreesEditField.Value;
            app.degrees = value;
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            value = app.AlphaEditField.Value;
            app.alpha_shear = value;
        end

        % Button pushed function: ScaleImageButton
        function ScaleImageButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('scale', app.x_scale, app.y_scale);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
            
        end

        % Button pushed function: TranslateImageButton
        function TranslateImageButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('translate', app.x_trans, app.y_trans);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('rotate', app.degrees);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
        end

        % Button pushed function: FlipVerticalButton
        function FlipVerticalButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('v-reflect');

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
        end

        % Button pushed function: FlipHorizontalButton
        function FlipHorizontalButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('h-reflect');

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
        end

        % Button pushed function: VerticalShearButton
        function VerticalShearButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('v-shear',app.alpha_shear);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
        end

        % Button pushed function: HorizontalShearButton
        function HorizontalShearButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('h-shear',app.alpha_shear);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
                app.filtered.ImageSource = g1;
                app.current_image = g1;
                app.done = 1;
            else
                app.filtered.ImageSource = g;
                app.current_image = g;
                app.done = 1;
            end
        end

        % Button pushed function: GaussianButton_4
        function GaussianButton_4Pushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.gauss_noise_image=imnoise2(app.im,'gaussian', app.a,app.b);
            app.filtered.ImageSource=app.gauss_noise_image;
            app.current_image=app.gauss_noise_image;
            app.image_array = cat(3, app.image_array, app.gauss_noise_image);
            app.done=1;

        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.a=(value);
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.b=(value);
        end

        % Button pushed function: SaltandPepperButton
        function SaltandPepperButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.SandP=imnoise2(app.im,'salt & pepper', app.a,app.b);
            app.filtered.ImageSource=app.SandP;
            app.current_image=app.SandP;
            app.image_array = cat(3, app.image_array, app.SandP);
            app.done=1;

        end

        % Button pushed function: RayleighButton
        function RayleighButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.rayleigh=imnoise2(app.im,'rayleigh', app.a,app.b);
            app.filtered.ImageSource=app.rayleigh;
            app.current_image=app.rayleigh;
            app.image_array = cat(3, app.image_array, app.rayleigh);
            app.done=1;

        end

        % Button pushed function: ErlangButton
        function ErlangButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.erlang=imnoise2(app.im,'erlang', app.a, double(app.b));
            app.filtered.ImageSource=app.erlang;
            app.current_image=app.erlang;
            app.image_array = cat(3, app.image_array, app.erlang);
            app.done=1;
        end

        % Button pushed function: ExponentialButton
        function ExponentialButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.exponential=imnoise2(app.im,'exponential', app.a,app.b);
            app.filtered.ImageSource=app.exponential;
            app.current_image=app.exponential;
            app.image_array = cat(3, app.image_array, app.exponential);
            app.done=1;
        end

        % Button pushed function: SinusoidalButton
        function SinusoidalButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            [M, N] = size(app.current_image);

            app.filtered.ImageSource=app.sinusoid;
            app.current_image=app.sinusoid;
            app.image_array = cat(3, app.image_array, app.sinusoid);
            app.done=1;
        end

        % Value changed function: arithmeticCheckBox
        function arithmeticCheckBoxValueChanged(app, event)
            value = app.arithmeticCheckBox.Value;
            
        end

        % Value changed function: geometricCheckBox
        function geometricCheckBoxValueChanged(app, event)
            value = app.geometricCheckBox.Value;
            
        end

        % Value changed function: harmonicCheckBox
        function harmonicCheckBoxValueChanged(app, event)
            value = app.harmonicCheckBox.Value;
            
        end

        % Value changed function: contraharmonicCheckBox
        function contraharmonicCheckBoxValueChanged(app, event)
            value = app.contraharmonicCheckBox.Value;
            
        end

        % Value changed function: medianCheckBox
        function medianCheckBoxValueChanged(app, event)
            value = app.medianCheckBox.Value;
            
        end

        % Value changed function: minCheckBox
        function minCheckBoxValueChanged(app, event)
            value = app.minCheckBox.Value;
            
        end

        % Value changed function: maxCheckBox
        function maxCheckBoxValueChanged(app, event)
            value = app.maxCheckBox.Value;
            
        end

        % Value changed function: midpointCheckBox
        function midpointCheckBoxValueChanged(app, event)
            value = app.midpointCheckBox.Value;
            
        end

        % Value changed function: alphatrimmedCheckBox
        function alphatrimmedCheckBoxValueChanged(app, event)
            value = app.alphatrimmedCheckBox.Value;
            
        end

        % Button pushed function: FilterButton_2
        function FilterButton_2Pushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            if app.arithmeticCheckBox.Value==1
                app.filt_im=spfilt(app.im, 'amean');
            elseif app.geometricCheckBox.Value==1
                app.filt_im=spfilt(app.im, 'gmean');
            elseif  app.harmonicCheckBox.Value==1
                app.filt_im=spfilt(app.im, 'hmean');
            elseif app.contraharmonicCheckBox.Value==1
                app.filt_im=spfilt(app.im, 'chmean');
            elseif app.medianCheckBox.Value==1
                image=rgb2gray(app.im);
                [m, n]=size(image);
                filt=medfilt2(image, [m, n], 'symmetric');
                app.filt_im=repmat(filt, [1 1 3]);
            elseif app.minCheckBox.Value==1
                app.filt_im=spfilt(app.im, 'min');
            elseif app.maxCheckBox.Value==1
                app.filt_im=spfilt(app.im, 'max');
            elseif app.midpointCheckBox.Value==1
                [m,n]=size(app.im);
                f1 = ordfilt2(app.im, 1, ones(m,n), 'symmetric');
                f2 = ordfilt2(app.im, m*n, ones(m,n), 'symmetric');
                app.filt_im = imlincomb(0.5, f1, 0.5, f2);
            elseif app.alphatrimmedCheckBox.Value==1
                image=rgb2gray(app.im);
                filt=spfilt(image, 'atrimmed');
                app.filt_im=repmat(filt, [1 1 3]);
            end
            app.filtered.ImageSource=app.filt_im;
            app.current_image=app.filt_im;
            app.image_array = cat(3, app.image_array, app.filt_im);
            app.done=1;
        end

        % Value changed function: GaussianCheckBox
        function GaussianCheckBoxValueChanged(app, event)
            value = app.GaussianCheckBox.Value;
            
        end

        % Value changed function: LaplacianCheckBox
        function LaplacianCheckBoxValueChanged(app, event)
            value = app.LaplacianCheckBox.Value;
            
        end

        % Value changed function: AverageCheckBox
        function AverageCheckBoxValueChanged(app, event)
            value = app.AverageCheckBox.Value;
            
        end

        % Value changed function: DiskCheckBox
        function DiskCheckBoxValueChanged(app, event)
            value = app.DiskCheckBox.Value;
            
        end

        % Value changed function: MotionCheckBox
        function MotionCheckBoxValueChanged(app, event)
            value = app.MotionCheckBox.Value;
            
        end

        % Value changed function: PrewittCheckBox
        function PrewittCheckBoxValueChanged(app, event)
            value = app.PrewittCheckBox.Value;
            
        end

        % Value changed function: LoGCheckBox
        function LoGCheckBoxValueChanged(app, event)
            value = app.LoGCheckBox.Value;
            
        end

        % Value changed function: SobelCheckBox
        function SobelCheckBoxValueChanged(app, event)
            value = app.SobelCheckBox.Value;
            
        end

        % Value changed function: hEditField_2
        function hEditField_2ValueChanged(app, event)
            value = app.hEditField_2.Value;
            
        end

        % Value changed function: thetaEditField_2
        function thetaEditField_2ValueChanged(app, event)
            value = app.thetaEditField_2.Value;
            
        end

        % Value changed function: radiusEditField_2
        function radiusEditField_2ValueChanged(app, event)
            value = app.radiusEditField_2.Value;
            
        end

        % Value changed function: sigmaEditField_2
        function sigmaEditField_2ValueChanged(app, event)
            value = app.sigmaEditField_2.Value;
            
        end

        % Value changed function: alphaEditField_2
        function alphaEditField_2ValueChanged(app, event)
            value = app.alphaEditField_2.Value;
            
        end

        % Value changed function: lenEditField_2
        function lenEditField_2ValueChanged(app, event)
            value = app.lenEditField_2.Value;
            
        end

        % Button pushed function: BlindDeconvolutionButton
        function BlindDeconvolutionButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            if app.GaussianCheckBox.Value==1
                psf=fspecial('gaussian', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.LaplacianCheckBox.Value==1
                psf=fspecial('laplacian', str2double(app.alphaEditField_2.Value));
            elseif app.AverageCheckBox.Value==1
                psf=fspecial('average', str2double(app.hEditField_2.Value));
            elseif app.DiskCheckBox.Value==1
                psf=fspecial('disk', str2double(app.radiusEditField_2.Value));
            elseif app.MotionCheckBox.Value==1
                psf=fspecial('motion', str2double(app.lenEditField_2.Value), str2double(app.thetaEditField_2,Value));
            elseif app.PrewittCheckBox.Value==1
                psf=fspecial('prewitt');
            elseif app.LoGCheckBox.Value==1
                psf=fspecial('log', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.SobelCheckBox.Value==1
                psf=fspecial('sobel');
            end
            app.blindDeconv=deconvblind(app.im, psf, app.iterationEditField.Value);
            app.filtered.ImageSource=app.blindDeconv;
            app.current_image=app.blindDeconv;
            app.image_array = cat(3, app.image_array, app.blindDeconv);
            app.done=1;
        end

        % Value changed function: iterationEditField
        function iterationEditFieldValueChanged(app, event)
            value = app.iterationEditField.Value;
            
        end

        % Value changed function: dampeningEditField
        function dampeningEditFieldValueChanged(app, event)
            value = app.dampeningEditField.Value;
            
        end

        % Button pushed function: ConstrainedLeastSquaresButton
        function ConstrainedLeastSquaresButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            if app.GaussianCheckBox.Value==1
                psf=fspecial('gaussian', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.LaplacianCheckBox.Value==1
                psf=fspecial('laplacian', str2double(app.alphaEditField_2.Value));
            elseif app.AverageCheckBox.Value==1
                      
                psf=fspecial('average', str2double(app.hEditField_2.Value));
            elseif app.DiskCheckBox.Value==1
                psf=fspecial('disk', str2double(app.radiusEditField_2.Value));
            elseif app.MotionCheckBox.Value==1
                psf=fspecial('motion', str2double(app.lenEditField_2.Value), str2double(app.thetaEditField_2,Value));
            elseif app.PrewittCheckBox.Value==1
                psf=fspecial('prewitt');
            elseif app.LoGCheckBox.Value==1
                psf=fspecial('log', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.SobelCheckBox.Value==1
                psf=fspecial('sobel');
            end
            blurred=imfilter(app.im, psf, 'conv', 'circular');
            blurred_noisy=imnoise(blurred, 'gaussian', app.meanEditField.Value, app.varEditField.Value);
            noise=blurred_noisy-blurred;
            [app.constLS, ~]=deconvreg(blurred_noisy, psf, app.noisepowerEditField.Value*app.noisepowerEditField_2.Value);
            app.filtered.ImageSource=app.constLS;
            app.current_image=app.constLS;
            app.image_array = cat(3, app.image_array, app.constLS);
            app.done=1;
        end

        % Value changed function: noisepowerEditField
        function noisepowerEditFieldValueChanged(app, event)
            value = app.noisepowerEditField.Value;
            
        end

        % Value changed function: noisepowerEditField_2
        function noisepowerEditField_2ValueChanged(app, event)
            value = app.noisepowerEditField_2.Value;
            
        end

        % Button pushed function: LucyRichardsonButton
        function LucyRichardsonButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            if app.GaussianCheckBox.Value==1
                psf=fspecial('gaussian', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.LaplacianCheckBox.Value==1
                psf=fspecial('laplacian', str2double(app.alphaEditField_2.Value));
            elseif app.AverageCheckBox.Value==1
                psf=fspecial('average', str2double(app.hEditField_2.Value));
            elseif app.DiskCheckBox.Value==1
                psf=fspecial('disk', str2double(app.radiusEditField_2.Value));
            elseif app.MotionCheckBox.Value==1
                psf=fspecial('motion', str2double(app.lenEditField_2.Value), str2double(app.thetaEditField_2,Value));
            elseif app.PrewittCheckBox.Value==1
                psf=fspecial('prewitt');
            elseif app.LoGCheckBox.Value==1
                psf=fspecial('log', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.SobelCheckBox.Value==1
                psf=fspecial('sobel');
            end
            blurred=imfilter(app.im, psf, 'conv', 'circular');
            blurred_noisy=imnoise(blurred, 'gaussian', app.meanEditField.Value, app.varEditField.Value);
            noise=blurred_noisy-blurred;
            if app.sqrtvarCheckBox.Value==1
                damp=0.5*sqrt(app.varEditField.Value);
            else
                damp=app.dampeningEditField.Value;
            end

            damp=uint8(damp);
            class(damp)
            class(app.im)
            app.lucy=deconvlucy(blurred_noisy, psf, app.iterationEditField.Value, damp);
            app.filtered.ImageSource=app.lucy;
            app.current_image=app.lucy;
            app.image_array = cat(3, app.image_array, app.lucy);
            app.done=1;
        end

        % Value changed function: NSPRCheckBox
        function NSPRCheckBoxValueChanged(app, event)
            value = app.NSPRCheckBox.Value;
            
        end

        % Value changed function: autocorrelationCheckBox
        function autocorrelationCheckBoxValueChanged(app, event)
            value = app.autocorrelationCheckBox.Value;
            
        end

        % Button pushed function: WienerFilterButton
        function WienerFilterButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            if app.GaussianCheckBox.Value==1
                psf=fspecial('gaussian', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.LaplacianCheckBox.Value==1
                psf=fspecial('laplacian', str2double(app.alphaEditField_2.Value));
            elseif app.AverageCheckBox.Value==1
                psf=fspecial('average', str2double(app.hEditField_2.Value));
            elseif app.DiskCheckBox.Value==1
                psf=fspecial('disk', str2double(app.radiusEditField_2.Value));
            elseif app.MotionCheckBox.Value==1
                psf=fspecial('motion', str2double(app.lenEditField_2.Value), str2double(app.thetaEditField_2,Value));
            elseif app.PrewittCheckBox.Value==1
                psf=fspecial('prewitt');
            elseif app.LoGCheckBox.Value==1
                psf=fspecial('log', str2double(app.hEditField_2.Value), str2double(app.sigmaEditField_2.Value));
            elseif app.SobelCheckBox.Value==1
                psf=fspecial('sobel');
            end
            blurred=imfilter(app.im, psf, 'conv', 'circular');
            blurred_noisy=imnoise(blurred, 'gaussian', app.meanEditField.Value, app.varEditField.Value);
            noise=blurred_noisy-blurred;
            Sn = abs(fft2(noise)).^2;       % Noise Power Spectrum
            nA = sum(Sn(:))/numel(noise);   % Noise Avg. Power
            Sf = abs(fft2(app.im)).^2;           % Image Power Spectrum
            fA = sum(Sf(:))/numel(noise);   % Image Avg. Power
            R = nA/fA; % NSPR
            if app.NSPRCheckBox.Value==1
                app.weiner = deconvwnr(blurred_noisy, psf, R);
            elseif app.autocorrelationCheckBox.Value==1
                NCORR = fftshift(ifft2(Sn));
                ICORR = fftshift(ifft2(Sf));
                app.weiner = deconvwnr(blurred_noisy, psf, real(NCORR), real(ICORR));
            end
            app.filtered.ImageSource=app.weiner;
            app.current_image=app.weiner;
            app.image_array = cat(3, app.image_array, app.weiner);
            app.done=1;
        end

        % Value changed function: sqrtvarCheckBox
        function sqrtvarCheckBoxValueChanged(app, event)
            value = app.sqrtvarCheckBox.Value;
            
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.M = value;
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.N = value;
        end

        % Value changed function: ClipLimitEditField
        function ClipLimitEditFieldValueChanged(app, event)
            value = app.ClipLimitEditField.Value;
            app.clip_limit = value;
        end

        % Value changed function: NumberofBinsEditField
        function NumberofBinsEditFieldValueChanged(app, event)
            value = app.NumberofBinsEditField.Value;
            app.num_bins = value;
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            if (app.ExponentialButton_2.Value == 1)
                L = adapthisteq(f,"NumTiles", [app.M, app.N],...
                    "ClipLimit", app.clip_limit,"NBins", app.num_bins,...
                    "Range", "full", "Distribution", "exponential", "Alpha",...
                    app.alpha_eq);
            elseif (app.RayleighButton_2.Value == 1)
                L = adapthisteq(f,"NumTiles", [app.M, app.N],...
                    "ClipLimit", app.clip_limit,"NBins", app.num_bins,...
                    "Range", "full", "Distribution", "rayleigh", "Alpha",...
                    app.alpha_eq);
            else
                L = adapthisteq(f,"NumTiles", [app.M, app.N],...
                    "ClipLimit", app.clip_limit,"NBins", app.num_bins,...
                    "Range", "full");
            end

            if isRGB == 1
                L1 = repmat(L, [1 1 3]);
                app.filtered.ImageSource = L1;
                app.current_image = L1;
                app.image_array = cat(3, app.image_array, L1);
                app.done = 1;
            else
                app.filtered.ImageSource = L;
                app.current_image = L;
                app.image_array = cat(3, app.image_array, L);
                app.done = 1;
            end

        end

        % Value changed function: AlphaEditField_2
        function AlphaEditField_2ValueChanged(app, event)
            value = app.AlphaEditField_2.Value;
            app.alpha_eq = value;
        end

        % Value changed function: ThetaSpacingSlider
        function ThetaSpacingSliderValueChanged(app, event)
            value = app.ThetaSpacingSlider.Value;
            app.theta_space = value;
        end

        % Button pushed function: RadonTransformButton
        function RadonTransformButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            if ndims(app.im) == 3
                f = app.im(:,:,1);
            else
                f = app.im;
            end

            theta_r = 0:app.theta_space:180;
            app.projection_im = radon(f,theta_r);
        end

        % Button pushed function: ReconstructionButton
        function ReconstructionButtonPushed(app, event)
            theta_r = 0:app.theta_space:180;
            
            if (app.NearestButton_2.Value == 1)
                interp = 'nearest';
            elseif (app.LinearButton.Value == 1)
                interp = 'linear';
            elseif (app.CubicButton.Value == 1)
                interp = 'cubic';
            elseif (app.SplineButton.Value == 1)
                interp = 'spline';
            end

            if (app.NoneButton_2.Value == 1)
                filter = 'none';
            elseif (app.HannButton.Value == 1)
                filter= 'Hann';
            elseif (app.HammingButton.Value == 1)
                filter = 'Hamming';
            elseif (app.CosineButton.Value == 1)
                filter = 'Cosine';
            elseif (app.SheppLoganButton.Value == 1)
                filter = 'Shepp-Logan';
            elseif (app.RamLakButton.Value == 1)
                filter = 'Ram-Lak';
            end

            g = iradon(app.projection_im, theta_r, interp, filter);

            g1 = repmat(g, [1 1 3]);
            app.filtered.ImageSource = g1;
            app.current_image = g1;
            app.done = 1;


        end

        % Value changed function: RotationIncrementSlider
        function RotationIncrementSliderValueChanged(app, event)
            value = app.RotationIncrementSlider.Value;
            app.rotation_inc = value;
        end

        % Value changed function: SensorSpacingSlider
        function SensorSpacingSliderValueChanged(app, event)
            value = app.SensorSpacingSlider.Value;
            app.sensor_space = value;
        end

        % Button pushed function: FanBeamTransformButton
        function FanBeamTransformButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            if ndims(app.im) == 3
                f = app.im(:,:,1);
            else
                f = app.im;
            end

            app.D = 1.5*hypot(size(f,1),size(f,2))/2;
            
            if (app.ArcButton.Value == 1)
                app.projection_im = fanbeam(f,app.D,'FanSensorGeometry', 'arc',...
                    'FanSensorSpacing',app.sensor_space, 'FanRotationIncrement',app.rotation_inc);
            elseif (app.LineButton.Value == 1)
                app.projection_im = fanbeam(f,app.D,'FanSensorGeometry', 'line',...
                    'FanSensorSpacing',app.sensor_space, 'FanRotationIncrement',app.rotation_inc);
            end

            g = repmat(app.projection_im, [1 1 3]);
            app.filtered.ImageSource = g;
            app.current_image = g;
            app.done = 1;
            

        end

        % Button pushed function: ReconstructionButton_2
        function ReconstructionButton_2Pushed(app, event)
            if (app.ArcButton.Value == 1)
                geom = 'arc';
            elseif (app.LineButton.Value == 1)
                geom = 'line';
            end

            if (app.NearestButton_2.Value == 1)
                interp = 'nearest';
            elseif (app.LinearButton.Value == 1)
                interp = 'linear';
            elseif (app.CubicButton.Value == 1)
                interp = 'cubic';
            elseif (app.SplineButton.Value == 1)
                interp = 'spline';
            end

            if (app.NoneButton_2.Value == 1)
                filter = 'none';
            elseif (app.HannButton == 1)
                filter= 'Hann';
            elseif (app.HammingButton == 1)
                filter = 'Hamming';
            elseif (app.CosineButton == 1)
                filter = 'Cosine';
            elseif (app.SheppLoganButton.Value == 1)
                filter = 'Shepp-Logan';
            elseif (app.RamLakButton.Value == 1)
                filter = 'Ram-Lak';
            end

            g = ifanbeam(app.projection_im, app.D, 'FanRotationIncrement', ...
                app.rotation_inc, 'FanSensorGeometry', geom, 'FanSensorSpacing',...
                app.sensor_space, 'Filter', filter, 'Interpolation', interp);

            g1 = repmat(g, [1 1 3]);
            app.filtered.ImageSource = g1;
            app.current_image = g1;
            app.done = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ECE480ImageProcessingMidtermProjectPanel = uipanel(app.UIFigure);
            app.ECE480ImageProcessingMidtermProjectPanel.Title = 'ECE 480 Image Processing Midterm Project';
            app.FileInformationPanel = uipanel(app.ECE480ImageProcessingMidtermProjectPanel);
            app.FileInformationPanel.Title = 'File Information';
            app.SelectImageButton = uibutton(app.FileInformationPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.FileDetailsButton = uibutton(app.FileInformationPanel, 'push');
            app.FileDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @FileDetailsButtonPushed, true);
            app.FileDetailsButton.Text = 'File Details';
            app.SaveButton = uibutton(app.FileInformationPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FilteredImagePanel = uipanel(app.ECE480ImageProcessingMidtermProjectPanel);
            app.FilteredImagePanel.Title = 'Filtered Image';
            app.filtered = uiimage(app.FilteredImagePanel);
            app.OrignalPanel = uipanel(app.ECE480ImageProcessingMidtermProjectPanel);
            app.OrignalPanel.Title = 'Orignal';
            app.orignal = uiimage(app.OrignalPanel);
            app.TabGroup = uitabgroup(app.ECE480ImageProcessingMidtermProjectPanel);
            app.IntensityandSpatialFiltering = uitab(app.TabGroup);
            app.IntensityandSpatialFiltering.Title = 'Intensity and Spatial Filtering';
            app.SharpenPanel = uipanel(app.IntensityandSpatialFiltering);
            app.SharpenPanel.Title = 'Sharpen';
            app.SharpenButton = uibutton(app.SharpenPanel, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.RadiusEditFieldLabel = uilabel(app.SharpenPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.SharpenPanel, 'text');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.AmountEditFieldLabel = uilabel(app.SharpenPanel);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.SharpenPanel, 'text');
            app.AmountEditField.ValueChangedFcn = createCallbackFcn(app, @AmountEditFieldValueChanged, true);
            app.ContrastStretchingPanel = uipanel(app.IntensityandSpatialFiltering);
            app.ContrastStretchingPanel.Title = 'Contrast Stretching';
            app.FilterButton = uibutton(app.ContrastStretchingPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.EEditFieldLabel = uilabel(app.ContrastStretchingPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.ContrastStretchingPanel, 'text');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.EEditField.ValueChangingFcn = createCallbackFcn(app, @EEditFieldValueChanging, true);
            app.KEditFieldLabel = uilabel(app.ContrastStretchingPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.ContrastStretchingPanel, 'text');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.ValueChangingFcn = createCallbackFcn(app, @KEditFieldValueChanging, true);
            app.KmeanvalueCheckBox = uicheckbox(app.ContrastStretchingPanel);
            app.KmeanvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @KmeanvalueCheckBoxValueChanged, true);
            app.KmeanvalueCheckBox.Text = 'K mean value';
            app.KernalFilteringPanel = uipanel(app.IntensityandSpatialFiltering);
            app.KernalFilteringPanel.Title = 'Kernal Filtering';
            app.GaussianButton = uibutton(app.KernalFilteringPanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.LaplacianButton = uibutton(app.KernalFilteringPanel, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.hEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.AverageButton = uibutton(app.KernalFilteringPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.DiskButton = uibutton(app.KernalFilteringPanel, 'push');
            app.DiskButton.ButtonPushedFcn = createCallbackFcn(app, @DiskButtonPushed, true);
            app.DiskButton.Text = 'Disk';
            app.LoGButton = uibutton(app.KernalFilteringPanel, 'push');
            app.LoGButton.ButtonPushedFcn = createCallbackFcn(app, @LoGButtonPushed, true);
            app.LoGButton.Text = 'LoG';
            app.MotionButton = uibutton(app.KernalFilteringPanel, 'push');
            app.MotionButton.ButtonPushedFcn = createCallbackFcn(app, @MotionButtonPushed, true);
            app.MotionButton.Text = 'Motion';
            app.PrewittButton = uibutton(app.KernalFilteringPanel, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.KernalFilteringPanel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.thetaEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.thetaEditField.ValueChangedFcn = createCallbackFcn(app, @thetaEditFieldValueChanged, true);
            app.radiusEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @radiusEditFieldValueChanged, true);
            app.sigmaEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.alphaEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.lenEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.lenEditFieldLabel.Text = 'len';
            app.lenEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.lenEditField.ValueChangedFcn = createCallbackFcn(app, @lenEditFieldValueChanged, true);
            app.AdaptiveHistogramEqualizationPanel = uipanel(app.IntensityandSpatialFiltering);
            app.AdaptiveHistogramEqualizationPanel.Title = 'Adaptive Histogram Equalization';
            app.MEditFieldLabel = uilabel(app.AdaptiveHistogramEqualizationPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.AdaptiveHistogramEqualizationPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.AdaptiveHistogramEqualizationPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.AdaptiveHistogramEqualizationPanel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NumberofTilesLable = uilabel(app.AdaptiveHistogramEqualizationPanel);
            app.NumberofTilesLable.Text = 'Number of Tiles';
            app.DistributionButtonGroup = uibuttongroup(app.AdaptiveHistogramEqualizationPanel);
            app.DistributionButtonGroup.Title = 'Distribution';
            app.UniformButton = uiradiobutton(app.DistributionButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.UniformButton.Value = true;
            app.RayleighButton_2 = uiradiobutton(app.DistributionButtonGroup);
            app.RayleighButton_2.Text = 'Rayleigh';
            app.ExponentialButton_2 = uiradiobutton(app.DistributionButtonGroup);
            app.ExponentialButton_2.Text = 'Exponential';
            app.ClipLimitEditFieldLabel = uilabel(app.AdaptiveHistogramEqualizationPanel);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.AdaptiveHistogramEqualizationPanel, 'numeric');
            app.ClipLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ClipLimitEditFieldValueChanged, true);
            app.NumberofBinsEditFieldLabel = uilabel(app.AdaptiveHistogramEqualizationPanel);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.AdaptiveHistogramEqualizationPanel, 'numeric');
            app.NumberofBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBinsEditFieldValueChanged, true);
            app.HistogramEqualizationButton = uibutton(app.AdaptiveHistogramEqualizationPanel, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.AlphaEditField_2Label = uilabel(app.AdaptiveHistogramEqualizationPanel);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField_2 = uieditfield(app.AdaptiveHistogramEqualizationPanel, 'numeric');
            app.AlphaEditField_2.ValueChangedFcn = createCallbackFcn(app, @AlphaEditField_2ValueChanged, true);
            app.LogTransformButton = uibutton(app.IntensityandSpatialFiltering, 'push');
            app.LogTransformButton.ButtonPushedFcn = createCallbackFcn(app, @LogTransformButtonPushed, true);
            app.LogTransformButton.Text = 'Log Transform';
            app.HistogramButton = uibutton(app.IntensityandSpatialFiltering, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.FrequencyDomainFilteringTab = uitab(app.TabGroup);
            app.FrequencyDomainFilteringTab.Title = 'Frequency Domain Filtering';
            app.ButterworthButton = uibutton(app.FrequencyDomainFilteringTab, 'push');
            app.ButterworthButton.ButtonPushedFcn = createCallbackFcn(app, @ButterworthButtonPushed, true);
            app.ButterworthButton.Text = 'Butterworth';
            app.IdealButton = uibutton(app.FrequencyDomainFilteringTab, 'push');
            app.IdealButton.ButtonPushedFcn = createCallbackFcn(app, @IdealButtonPushed, true);
            app.IdealButton.Text = 'Ideal';
            app.GaussianButton_2 = uibutton(app.FrequencyDomainFilteringTab, 'push');
            app.GaussianButton_2.ButtonPushedFcn = createCallbackFcn(app, @GaussianButton_2Pushed, true);
            app.GaussianButton_2.Text = 'Gaussian';
            app.FilterTypeButtonGroup = uibuttongroup(app.FrequencyDomainFilteringTab);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowpassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.LowpassButton.Value = true;
            app.HighpassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighpassButton.Text = 'Highpass';
            app.BandpassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandpassButton.Text = 'Bandpass';
            app.BandrejectButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandrejectButton.Text = 'Bandreject';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.CutoffFrequencyEditField.Limits = [0 Inf];
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyEditFieldValueChanged, true);
            app.FilterOrderEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.CenterFrequencyEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.CenterFrequencyEditFieldLabel.Text = 'Center Frequency';
            app.CenterFrequencyEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.CenterFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyEditFieldValueChanged, true);
            app.WidthoftheBandEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.WidthoftheBandEditFieldLabel.Text = 'Width of the Band';
            app.WidthoftheBandEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.WidthoftheBandEditField.ValueChangedFcn = createCallbackFcn(app, @WidthoftheBandEditFieldValueChanged, true);
            app.PaddingMethodDropDownLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.PaddingMethodDropDownLabel.Text = 'Padding Method';
            app.PaddingMethodDropDown = uidropdown(app.FrequencyDomainFilteringTab);
            app.PaddingMethodDropDown.Items = {'symmetric', 'zeros', 'replicate', 'circular'};
            app.PaddingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PaddingMethodDropDownValueChanged, true);
            app.PaddingMethodDropDown.Value = 'symmetric';
            app.ShowFFTPlotsSwitchLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.ShowFFTPlotsSwitchLabel.Text = 'Show FFT Plots';
            app.ShowFFTPlotsSwitch = uiswitch(app.FrequencyDomainFilteringTab, 'slider');
            app.ShowFFTPlotsSwitch.ItemsData = [0 1];
            app.ShowFFTPlotsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowFFTPlotsSwitchValueChanged, true);
            app.ShowFFTPlotsSwitch.Value = 0;
            app.NotchFiltering = uipanel(app.FrequencyDomainFilteringTab);
            app.NotchFiltering.Title = 'Notch Filtering';
            app.NotchType = uibuttongroup(app.NotchFiltering);
            app.NotchType.Title = 'Notch Type';
            app.RejectButton = uiradiobutton(app.NotchType);
            app.RejectButton.Text = 'Reject';
            app.RejectButton.Value = true;
            app.PassButton = uiradiobutton(app.NotchType);
            app.PassButton.Text = 'Pass';
            app.Notchfiltertype = uibuttongroup(app.NotchFiltering);
            app.Notchfiltertype.Title = 'Notch FIlter Type';
            app.IdealButton_2 = uiradiobutton(app.Notchfiltertype);
            app.IdealButton_2.Text = 'Ideal';
            app.IdealButton_2.Value = true;
            app.ButterworthButton_2 = uiradiobutton(app.Notchfiltertype);
            app.ButterworthButton_2.Text = 'Butterworth';
            app.GaussianButton_3 = uiradiobutton(app.Notchfiltertype);
            app.GaussianButton_3.Text = 'Gaussian';
            app.UndoButton = uibutton(app.NotchFiltering, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ClearAllButton = uibutton(app.NotchFiltering, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ApplyFilterButton = uibutton(app.NotchFiltering, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.PlaceNotchesButton = uibutton(app.NotchFiltering, 'push');
            app.PlaceNotchesButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceNotchesButtonPushed, true);
            app.PlaceNotchesButton.Text = 'Place Notches';
            app.NotchRadiusEditFieldLabel = uilabel(app.NotchFiltering);
            app.NotchRadiusEditFieldLabel.Text = 'Notch Radius';
            app.NotchRadiusEditField = uieditfield(app.NotchFiltering, 'numeric');
            app.NotchRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @NotchRadiusEditFieldValueChanged, true);
            app.NotchFIlterOrderEditFieldLabel = uilabel(app.NotchFiltering);
            app.NotchFIlterOrderEditFieldLabel.Text = 'Notch FIlter Order';
            app.NotchFIlterOrderEditField = uieditfield(app.NotchFiltering, 'numeric');
            app.NotchFIlterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @NotchFIlterOrderEditFieldValueChanged, true);
            app.ImageRestorationTab = uitab(app.TabGroup);
            app.ImageRestorationTab.Title = 'Image Restoration';
            app.NoiseFilteringPanel = uipanel(app.ImageRestorationTab);
            app.NoiseFilteringPanel.Title = 'Noise Filtering';
            app.GaussianButton_4 = uibutton(app.NoiseFilteringPanel, 'push');
            app.GaussianButton_4.ButtonPushedFcn = createCallbackFcn(app, @GaussianButton_4Pushed, true);
            app.GaussianButton_4.Text = 'Gaussian';
            app.SaltandPepperButton = uibutton(app.NoiseFilteringPanel, 'push');
            app.SaltandPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltandPepperButtonPushed, true);
            app.SaltandPepperButton.Text = 'Salt and Pepper';
            app.RayleighButton = uibutton(app.NoiseFilteringPanel, 'push');
            app.RayleighButton.ButtonPushedFcn = createCallbackFcn(app, @RayleighButtonPushed, true);
            app.RayleighButton.Text = 'Rayleigh';
            app.ErlangButton = uibutton(app.NoiseFilteringPanel, 'push');
            app.ErlangButton.ButtonPushedFcn = createCallbackFcn(app, @ErlangButtonPushed, true);
            app.ErlangButton.Text = 'Erlang';
            app.ExponentialButton = uibutton(app.NoiseFilteringPanel, 'push');
            app.ExponentialButton.ButtonPushedFcn = createCallbackFcn(app, @ExponentialButtonPushed, true);
            app.ExponentialButton.Text = 'Exponential';
            app.SinusoidalButton = uibutton(app.NoiseFilteringPanel, 'push');
            app.SinusoidalButton.ButtonPushedFcn = createCallbackFcn(app, @SinusoidalButtonPushed, true);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.FilterButton_2 = uibutton(app.NoiseFilteringPanel, 'push');
            app.FilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilterButton_2Pushed, true);
            app.FilterButton_2.Text = 'Filter';
            app.arithmeticCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.arithmeticCheckBox.ValueChangedFcn = createCallbackFcn(app, @arithmeticCheckBoxValueChanged, true);
            app.arithmeticCheckBox.Text = 'arithmetic';
            app.geometricCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.geometricCheckBox.ValueChangedFcn = createCallbackFcn(app, @geometricCheckBoxValueChanged, true);
            app.geometricCheckBox.Text = 'geometric';
            app.harmonicCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.harmonicCheckBox.ValueChangedFcn = createCallbackFcn(app, @harmonicCheckBoxValueChanged, true);
            app.harmonicCheckBox.Text = 'harmonic';
            app.contraharmonicCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.contraharmonicCheckBox.ValueChangedFcn = createCallbackFcn(app, @contraharmonicCheckBoxValueChanged, true);
            app.contraharmonicCheckBox.Text = 'contraharmonic';
            app.medianCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.medianCheckBox.ValueChangedFcn = createCallbackFcn(app, @medianCheckBoxValueChanged, true);
            app.medianCheckBox.Text = 'median';
            app.minCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.minCheckBox.ValueChangedFcn = createCallbackFcn(app, @minCheckBoxValueChanged, true);
            app.minCheckBox.Text = 'min';
            app.maxCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.maxCheckBox.ValueChangedFcn = createCallbackFcn(app, @maxCheckBoxValueChanged, true);
            app.maxCheckBox.Text = 'max';
            app.midpointCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.midpointCheckBox.ValueChangedFcn = createCallbackFcn(app, @midpointCheckBoxValueChanged, true);
            app.midpointCheckBox.Text = 'midpoint';
            app.alphatrimmedCheckBox = uicheckbox(app.NoiseFilteringPanel);
            app.alphatrimmedCheckBox.ValueChangedFcn = createCallbackFcn(app, @alphatrimmedCheckBoxValueChanged, true);
            app.alphatrimmedCheckBox.Text = 'alpha trimmed';
            app.aEditFieldLabel = uilabel(app.NoiseFilteringPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.NoiseFilteringPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.NoiseFilteringPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.NoiseFilteringPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.PointsSquareFunctionPanel = uipanel(app.ImageRestorationTab);
            app.PointsSquareFunctionPanel.Title = 'Points Square Function';
            app.hEditField_2Label = uilabel(app.PointsSquareFunctionPanel);
            app.hEditField_2Label.Text = 'h';
            app.hEditField_2 = uieditfield(app.PointsSquareFunctionPanel, 'text');
            app.hEditField_2.ValueChangedFcn = createCallbackFcn(app, @hEditField_2ValueChanged, true);
            app.thetaEditField_2Label = uilabel(app.PointsSquareFunctionPanel);
            app.thetaEditField_2Label.Text = 'theta';
            app.thetaEditField_2 = uieditfield(app.PointsSquareFunctionPanel, 'text');
            app.thetaEditField_2.ValueChangedFcn = createCallbackFcn(app, @thetaEditField_2ValueChanged, true);
            app.radiusEditField_2Label = uilabel(app.PointsSquareFunctionPanel);
            app.radiusEditField_2Label.Text = 'radius';
            app.radiusEditField_2 = uieditfield(app.PointsSquareFunctionPanel, 'text');
            app.radiusEditField_2.ValueChangedFcn = createCallbackFcn(app, @radiusEditField_2ValueChanged, true);
            app.sigmaEditField_2Label = uilabel(app.PointsSquareFunctionPanel);
            app.sigmaEditField_2Label.Text = 'sigma';
            app.sigmaEditField_2 = uieditfield(app.PointsSquareFunctionPanel, 'text');
            app.sigmaEditField_2.ValueChangedFcn = createCallbackFcn(app, @sigmaEditField_2ValueChanged, true);
            app.alphaEditField_2Label = uilabel(app.PointsSquareFunctionPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField_2 = uieditfield(app.PointsSquareFunctionPanel, 'text');
            app.alphaEditField_2.ValueChangedFcn = createCallbackFcn(app, @alphaEditField_2ValueChanged, true);
            app.lenEditField_2Label = uilabel(app.PointsSquareFunctionPanel);
            app.lenEditField_2Label.Text = 'len';
            app.lenEditField_2 = uieditfield(app.PointsSquareFunctionPanel, 'text');
            app.lenEditField_2.ValueChangedFcn = createCallbackFcn(app, @lenEditField_2ValueChanged, true);
            app.GaussianCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.GaussianCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianCheckBoxValueChanged, true);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.LaplacianCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.LaplacianCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaplacianCheckBoxValueChanged, true);
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.AverageCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.AverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageCheckBoxValueChanged, true);
            app.AverageCheckBox.Text = 'Average';
            app.DiskCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.DiskCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiskCheckBoxValueChanged, true);
            app.DiskCheckBox.Text = 'Disk';
            app.MotionCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.MotionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotionCheckBoxValueChanged, true);
            app.MotionCheckBox.Text = 'Motion';
            app.PrewittCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.PrewittCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrewittCheckBoxValueChanged, true);
            app.PrewittCheckBox.Text = 'Prewitt';
            app.LoGCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.LoGCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoGCheckBoxValueChanged, true);
            app.LoGCheckBox.Text = 'LoG';
            app.SobelCheckBox = uicheckbox(app.PointsSquareFunctionPanel);
            app.SobelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SobelCheckBoxValueChanged, true);
            app.SobelCheckBox.Text = 'Sobel';
            app.FilterPanel = uipanel(app.ImageRestorationTab);
            app.FilterPanel.Title = 'Filter';
            app.meanEditFieldLabel = uilabel(app.FilterPanel);
            app.meanEditFieldLabel.Text = 'mean';
            app.meanEditField = uieditfield(app.FilterPanel, 'numeric');
            app.varEditFieldLabel = uilabel(app.FilterPanel);
            app.varEditFieldLabel.Text = 'var';
            app.varEditField = uieditfield(app.FilterPanel, 'numeric');
            app.WienerFilterButton = uibutton(app.FilterPanel, 'push');
            app.WienerFilterButton.ButtonPushedFcn = createCallbackFcn(app, @WienerFilterButtonPushed, true);
            app.WienerFilterButton.Text = 'Wiener Filter';
            app.noisepowerEditFieldLabel = uilabel(app.FilterPanel);
            app.noisepowerEditFieldLabel.Text = 'noise power';
            app.noisepowerEditField = uieditfield(app.FilterPanel, 'numeric');
            app.noisepowerEditField.ValueChangedFcn = createCallbackFcn(app, @noisepowerEditFieldValueChanged, true);
            app.ConstrainedLeastSquaresButton = uibutton(app.FilterPanel, 'push');
            app.ConstrainedLeastSquaresButton.ButtonPushedFcn = createCallbackFcn(app, @ConstrainedLeastSquaresButtonPushed, true);
            app.ConstrainedLeastSquaresButton.Text = 'Constrained Least Squares';
            app.noisepowerEditField_2Label = uilabel(app.FilterPanel);
            app.noisepowerEditField_2Label.Text = '% noise power';
            app.noisepowerEditField_2 = uieditfield(app.FilterPanel, 'numeric');
            app.noisepowerEditField_2.ValueChangedFcn = createCallbackFcn(app, @noisepowerEditField_2ValueChanged, true);
            app.LucyRichardsonButton = uibutton(app.FilterPanel, 'push');
            app.LucyRichardsonButton.ButtonPushedFcn = createCallbackFcn(app, @LucyRichardsonButtonPushed, true);
            app.LucyRichardsonButton.Text = 'Lucy Richardson';
            app.iterationEditFieldLabel = uilabel(app.FilterPanel);
            app.iterationEditFieldLabel.Text = 'iteration';
            app.iterationEditField = uieditfield(app.FilterPanel, 'numeric');
            app.iterationEditField.ValueChangedFcn = createCallbackFcn(app, @iterationEditFieldValueChanged, true);
            app.dampeningEditFieldLabel = uilabel(app.FilterPanel);
            app.dampeningEditFieldLabel.Text = 'dampening';
            app.dampeningEditField = uieditfield(app.FilterPanel, 'numeric');
            app.dampeningEditField.ValueChangedFcn = createCallbackFcn(app, @dampeningEditFieldValueChanged, true);
            app.BlindDeconvolutionButton = uibutton(app.FilterPanel, 'push');
            app.BlindDeconvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @BlindDeconvolutionButtonPushed, true);
            app.BlindDeconvolutionButton.Text = 'Blind Deconvolution';
            app.autocorrelationCheckBox = uicheckbox(app.FilterPanel);
            app.autocorrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @autocorrelationCheckBoxValueChanged, true);
            app.autocorrelationCheckBox.Text = 'autocorrelation';
            app.NSPRCheckBox = uicheckbox(app.FilterPanel);
            app.NSPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @NSPRCheckBoxValueChanged, true);
            app.NSPRCheckBox.Text = 'NSPR';
            app.sqrtvarCheckBox = uicheckbox(app.FilterPanel);
            app.sqrtvarCheckBox.ValueChangedFcn = createCallbackFcn(app, @sqrtvarCheckBoxValueChanged, true);
            app.sqrtvarCheckBox.Text = 'sqrt var';
            app.ImageReconstructionTab = uitab(app.TabGroup);
            app.ImageReconstructionTab.Title = 'Image Reconstruction';
            app.ParallelBeamProjectionsPanel = uipanel(app.ImageReconstructionTab);
            app.ParallelBeamProjectionsPanel.Title = 'Parallel-Beam Projections';
            app.ThetaSpacingSliderLabel = uilabel(app.ParallelBeamProjectionsPanel);
            app.ThetaSpacingSliderLabel.Text = 'Theta Spacing';
            app.ThetaSpacingSlider = uislider(app.ParallelBeamProjectionsPanel);
            app.ThetaSpacingSlider.Limits = [0.01 1];
            app.ThetaSpacingSlider.MajorTicks = [0.01 0.2 0.4 0.6 0.8 1];
            app.ThetaSpacingSlider.ValueChangedFcn = createCallbackFcn(app, @ThetaSpacingSliderValueChanged, true);
            app.ThetaSpacingSlider.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.ThetaSpacingSlider.Value = 1;
            app.RadonTransformButton = uibutton(app.ParallelBeamProjectionsPanel, 'push');
            app.RadonTransformButton.ButtonPushedFcn = createCallbackFcn(app, @RadonTransformButtonPushed, true);
            app.RadonTransformButton.Text = 'Radon Transform';
            app.ReconstructionButton = uibutton(app.ParallelBeamProjectionsPanel, 'push');
            app.ReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButtonPushed, true);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.InterpolationMethodButtonGroup_2 = uibuttongroup(app.ParallelBeamProjectionsPanel);
            app.InterpolationMethodButtonGroup_2.Title = 'Interpolation Method';
            app.NearestButton_2 = uiradiobutton(app.InterpolationMethodButtonGroup_2);
            app.NearestButton_2.Text = 'Nearest';
            app.NearestButton_2.Value = true;
            app.LinearButton = uiradiobutton(app.InterpolationMethodButtonGroup_2);
            app.LinearButton.Text = 'Linear';
            app.CubicButton = uiradiobutton(app.InterpolationMethodButtonGroup_2);
            app.CubicButton.Text = 'Cubic';
            app.SplineButton = uiradiobutton(app.InterpolationMethodButtonGroup_2);
            app.SplineButton.Text = 'Spline';
            app.FilterTypeButtonGroup_2 = uibuttongroup(app.ParallelBeamProjectionsPanel);
            app.FilterTypeButtonGroup_2.Title = 'Filter Type';
            app.RamLakButton = uiradiobutton(app.FilterTypeButtonGroup_2);
            app.RamLakButton.Text = 'Ram-Lak';
            app.RamLakButton.Value = true;
            app.SheppLoganButton = uiradiobutton(app.FilterTypeButtonGroup_2);
            app.SheppLoganButton.Text = 'Shepp-Logan';
            app.CosineButton = uiradiobutton(app.FilterTypeButtonGroup_2);
            app.CosineButton.Text = 'Cosine';
            app.HammingButton = uiradiobutton(app.FilterTypeButtonGroup_2);
            app.HammingButton.Text = 'Hamming';
            app.HannButton = uiradiobutton(app.FilterTypeButtonGroup_2);
            app.HannButton.Text = 'Hann';
            app.NoneButton_2 = uiradiobutton(app.FilterTypeButtonGroup_2);
            app.NoneButton_2.Text = 'None';
            app.FanBeamProjectionsPanel = uipanel(app.ImageReconstructionTab);
            app.FanBeamProjectionsPanel.Title = 'Fan-Beam Projections';
            app.RotationIncrementSliderLabel = uilabel(app.FanBeamProjectionsPanel);
            app.RotationIncrementSliderLabel.Text = 'Rotation Increment';
            app.RotationIncrementSlider = uislider(app.FanBeamProjectionsPanel);
            app.RotationIncrementSlider.Limits = [0.01 1];
            app.RotationIncrementSlider.MajorTicks = [0.01 0.2 0.4 0.6 0.8 1];
            app.RotationIncrementSlider.ValueChangedFcn = createCallbackFcn(app, @RotationIncrementSliderValueChanged, true);
            app.RotationIncrementSlider.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.RotationIncrementSlider.Value = 1;
            app.FanBeamTransformButton = uibutton(app.FanBeamProjectionsPanel, 'push');
            app.FanBeamTransformButton.ButtonPushedFcn = createCallbackFcn(app, @FanBeamTransformButtonPushed, true);
            app.FanBeamTransformButton.Text = 'Fan-Beam Transform';
            app.SensorGeometryButtonGroup = uibuttongroup(app.FanBeamProjectionsPanel);
            app.SensorGeometryButtonGroup.Title = 'Sensor Geometry';
            app.ArcButton = uiradiobutton(app.SensorGeometryButtonGroup);
            app.ArcButton.Text = 'Arc';
            app.ArcButton.Value = true;
            app.LineButton = uiradiobutton(app.SensorGeometryButtonGroup);
            app.LineButton.Text = 'Line';
            app.ReconstructionButton_2 = uibutton(app.FanBeamProjectionsPanel, 'push');
            app.ReconstructionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButton_2Pushed, true);
            app.ReconstructionButton_2.Text = 'Reconstruction';
            app.SensorSpacingSliderLabel = uilabel(app.FanBeamProjectionsPanel);
            app.SensorSpacingSliderLabel.Text = 'Sensor Spacing';
            app.SensorSpacingSlider = uislider(app.FanBeamProjectionsPanel);
            app.SensorSpacingSlider.Limits = [0.01 1];
            app.SensorSpacingSlider.MajorTicks = [0.01 0.2 0.4 0.6 0.8 1];
            app.SensorSpacingSlider.ValueChangedFcn = createCallbackFcn(app, @SensorSpacingSliderValueChanged, true);
            app.SensorSpacingSlider.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.SensorSpacingSlider.Value = 1;
            app.FilterTypeButtonGroup_3 = uibuttongroup(app.FanBeamProjectionsPanel);
            app.FilterTypeButtonGroup_3.Title = 'Filter Type';
            app.RamLakButton_2 = uiradiobutton(app.FilterTypeButtonGroup_3);
            app.RamLakButton_2.Text = 'Ram-Lak';
            app.RamLakButton_2.Value = true;
            app.SheppLoganButton_2 = uiradiobutton(app.FilterTypeButtonGroup_3);
            app.SheppLoganButton_2.Text = 'Shepp-Logan';
            app.CosineButton_2 = uiradiobutton(app.FilterTypeButtonGroup_3);
            app.CosineButton_2.Text = 'Cosine';
            app.HammingButton_2 = uiradiobutton(app.FilterTypeButtonGroup_3);
            app.HammingButton_2.Text = 'Hamming';
            app.HannButton_2 = uiradiobutton(app.FilterTypeButtonGroup_3);
            app.HannButton_2.Text = 'Hann';
            app.NoneButton_3 = uiradiobutton(app.FilterTypeButtonGroup_3);
            app.NoneButton_3.Text = 'None';
            app.InterpolationButtonGroup = uibuttongroup(app.FanBeamProjectionsPanel);
            app.InterpolationButtonGroup.Title = 'Interpolation';
            app.NearestButton_3 = uiradiobutton(app.InterpolationButtonGroup);
            app.NearestButton_3.Text = 'Nearest';
            app.NearestButton_3.Value = true;
            app.LinearButton_2 = uiradiobutton(app.InterpolationButtonGroup);
            app.LinearButton_2.Text = 'Linear';
            app.CubicButton_2 = uiradiobutton(app.InterpolationButtonGroup);
            app.CubicButton_2.Text = 'Cubic';
            app.SplineButton_2 = uiradiobutton(app.InterpolationButtonGroup);
            app.SplineButton_2.Text = 'Spline';
            app.Transformations = uitab(app.TabGroup);
            app.Transformations.Title = 'Transformations';
            app.XScalingEditFieldLabel = uilabel(app.Transformations);
            app.XScalingEditFieldLabel.Text = 'X Scaling';
            app.XScalingEditField = uieditfield(app.Transformations, 'numeric');
            app.XScalingEditField.ValueChangedFcn = createCallbackFcn(app, @XScalingEditFieldValueChanged, true);
            app.XScalingEditField.Value = 1;
            app.YScalingEditFieldLabel = uilabel(app.Transformations);
            app.YScalingEditFieldLabel.Text = 'Y Scaling';
            app.YScalingEditField = uieditfield(app.Transformations, 'numeric');
            app.YScalingEditField.ValueChangedFcn = createCallbackFcn(app, @YScalingEditFieldValueChanged, true);
            app.YScalingEditField.Value = 1;
            app.ScaleImageButton = uibutton(app.Transformations, 'push');
            app.ScaleImageButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleImageButtonPushed, true);
            app.ScaleImageButton.Text = 'Scale Image';
            app.TranslateImageButton = uibutton(app.Transformations, 'push');
            app.TranslateImageButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateImageButtonPushed, true);
            app.TranslateImageButton.Text = 'Translate Image';
            app.XTranslateEditFieldLabel = uilabel(app.Transformations);
            app.XTranslateEditFieldLabel.Text = 'X Translate';
            app.XTranslateEditField = uieditfield(app.Transformations, 'numeric');
            app.XTranslateEditField.ValueChangedFcn = createCallbackFcn(app, @XTranslateEditFieldValueChanged, true);
            app.YTranslateEditFieldLabel = uilabel(app.Transformations);
            app.YTranslateEditFieldLabel.Text = 'Y Translate';
            app.YTranslateEditField = uieditfield(app.Transformations, 'numeric');
            app.YTranslateEditField.ValueChangedFcn = createCallbackFcn(app, @YTranslateEditFieldValueChanged, true);
            app.DegreesEditFieldLabel = uilabel(app.Transformations);
            app.DegreesEditFieldLabel.Text = 'Degrees';
            app.DegreesEditField = uieditfield(app.Transformations, 'numeric');
            app.DegreesEditField.ValueChangedFcn = createCallbackFcn(app, @DegreesEditFieldValueChanged, true);
            app.RotateImageButton = uibutton(app.Transformations, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Text = 'Rotate Image';
            app.FlipVerticalButton = uibutton(app.Transformations, 'push');
            app.FlipVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticalButtonPushed, true);
            app.FlipVerticalButton.Text = 'Flip Vertical';
            app.FlipHorizontalButton = uibutton(app.Transformations, 'push');
            app.FlipHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontalButtonPushed, true);
            app.FlipHorizontalButton.Text = 'Flip Horizontal';
            app.VerticalShearButton = uibutton(app.Transformations, 'push');
            app.VerticalShearButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalShearButtonPushed, true);
            app.VerticalShearButton.Text = 'Vertical Shear';
            app.AlphaEditFieldLabel = uilabel(app.Transformations);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.Transformations, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.HorizontalShearButton = uibutton(app.Transformations, 'push');
            app.HorizontalShearButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalShearButtonPushed, true);
            app.HorizontalShearButton.Text = 'Horizontal Shear';
            app.InterpolationMethodButtonGroup = uibuttongroup(app.Transformations);
            app.InterpolationMethodButtonGroup.Title = 'Interpolation Method';
            app.NoneButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.NearestButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.NearestButton.Text = 'Nearest';
            app.BilinearButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.BilinearButton.Text = 'Bilinear';
            app.BicubicButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.BicubicButton.Text = 'Bicubic';
            app.RestartButton = uibutton(app.ECE480ImageProcessingMidtermProjectPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.UndoButton_2 = uibutton(app.ECE480ImageProcessingMidtermProjectPanel, 'push');
            app.UndoButton_2.ButtonPushedFcn = createCallbackFcn(app, @UndoButton_2Pushed, true);
            app.UndoButton_2.Text = 'Undo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ece480_final_project_VER_1_2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end